classdef MLASST_CHINESE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MLASSTUIFigure            matlab.ui.Figure
        Label_3                   matlab.ui.control.Label
        CurrentLayer              matlab.ui.control.NumericEditField
        Image                     matlab.ui.control.Image
        PercentValue              matlab.ui.control.NumericEditField
        PercentSign               matlab.ui.control.EditField
        Switch                    matlab.ui.control.Switch
        Label_2                   matlab.ui.control.Label
        USFLAG                    matlab.ui.control.Image
        CHINAFLAG                 matlab.ui.control.Image
        TabGroup                  matlab.ui.container.TabGroup
        MLASSTTab                 matlab.ui.container.Tab
        LayersDisplay             matlab.ui.control.NumericEditField
        MonteCarloRuns            matlab.ui.control.NumericEditField
        Button                    matlab.ui.control.Button
        GNSSSIGNALISOLATIONVIAPOWERANDDISTORTIONDYNAMICMONTECARLOLabel  matlab.ui.control.Label
        Panel_8                   matlab.ui.container.Panel
        Button_2                  matlab.ui.control.Button
        PmdAlarmsSwitch           matlab.ui.control.Switch
        DimentionsSwitch          matlab.ui.control.Switch
        RunSIm                    matlab.ui.control.Button
        Panel_7                   matlab.ui.container.Panel
        Label_4                   matlab.ui.control.Label
        ListBox                   matlab.ui.control.ListBox
        Panel_9                   matlab.ui.container.Panel
        PFAandLabel               matlab.ui.control.Label
        PFAand                    matlab.ui.control.NumericEditField
        PFAorLabel                matlab.ui.control.Label
        PFAor                     matlab.ui.control.NumericEditField
        BackgroundNoise           matlab.ui.control.NumericEditField
        BackgroundNoiseLabel      matlab.ui.control.Label
        NominalSignalPowerLabel   matlab.ui.control.Label
        NominalSignalPower        matlab.ui.control.NumericEditField
        SDpowerMetricLabel        matlab.ui.control.Label
        SDpowerMetric             matlab.ui.control.NumericEditField
        LowestSPA                 matlab.ui.control.NumericEditField
        LowestSPALabel            matlab.ui.control.Label
        EarlyLateCorrelatorLabel  matlab.ui.control.Label
        EarlyLateCorrelator       matlab.ui.control.NumericEditField
        HighestSPALabel           matlab.ui.control.Label
        HighestSPA                matlab.ui.control.NumericEditField
        FalseAlertLabel           matlab.ui.control.Label
        FalseAlert                matlab.ui.control.NumericEditField
        Panel_10                  matlab.ui.container.Panel
        MonteCarloRunsLabel       matlab.ui.control.Label
        Panel_11                  matlab.ui.container.Panel
        LayersDisplayLabel        matlab.ui.control.Label
        Panel_12                  matlab.ui.container.Panel
        HoldGraphSwitch           matlab.ui.control.Switch
        Label                     matlab.ui.control.Label
        ResultsMLASC              matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
app.MLASSTUIFigure.Name = 'MLASST SOFTWARE - NUAA';
movegui(app.MLASSTUIFigure, 'center');
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            if strcmp(value, 'ENGLISH')
                close (app.MLASSTUIFigure);
                MLASST_ENGLISH;
            end
        end

        % Image clicked function: USFLAG
        function USFLAGImageClicked(app, event)
                close (app.MLASSTUIFigure);
                MLASST_ENGLISH; 
        end

        % Value changed function: PercentValue
        function PercentValueValueChanged(app, event)
            app.PercentValue.Value;
        end

        % Button pushed function: RunSIm
        function RunSImPushed(app, event)
% prepare workspace

close all;
rng(1); % for reproducability
%% set simulation paramters

CN0nom = app.NominalSignalPower.Value; % nominal signal strength in dB-Hz
sigP = app.SDpowerMetric.Value; % standard deviation of power metric in dB
ELdist = app.EarlyLateCorrelator.Value; % early/late correlator positions in chips from prompt
P_FA = app.FalseAlert.Value; % false alert probability
lspa = app.LowestSPA.Value;
hspa = app.HighestSPA.Value;
deltaCN0s = lspa:hspa; % different spoofer power advantages in dB
N = app.MonteCarloRuns.Value; % number of monte carlo runs at every epoch 
PFA_or = app.PFAor.Value;
PFA_and = app.PFAand.Value;
layer = app.LayersDisplay.Value;
app.CurrentLayer.Value = layer;
offsets = 0:0.01:(1+ELdist+0.01); % offsets of spoofer's triangle in code chips
dx = 0.001;
x = -1-min(offsets):dx:1+max(offsets); % code chip range around authentic peak
xTI = round(ELdist/dx) * [-1 0 1]; % correlation tab spacing in units of x

app.RunSIm.Text = '模拟进行中';
app.RunSIm.Icon = 'loading.gif';
for loader = 0:1
loader = loader + 1/N;
end
h=waitbar(loader,'模拟进行中');
app.RunSIm.Text = '模拟已完成，见结果';
app.RunSIm.Icon = 'tick.png';
app.PercentValue.Value = 100;
close(h);

% calculate height of nominal signal peak at each epoch
nomPeak = expectedPeaksize(CN0nom);
nomCorrFun = nomPeak .* Rcorr(x'); % nominal correlation triangle

%% run simulation during lift-off
deltaMetric = zeros(length(deltaCN0s), length(offsets));
peakSize = zeros(length(deltaCN0s), length(offsets));

[meanLLD, maxLLD, meanLLP, maxLLP] = deal(zeros(1, length(deltaCN0s)));

for deltaI = 1:length(deltaCN0s)
    deltaCN0 = deltaCN0s(deltaI); % spoofer power advantage


    %% set peak sizes
    CN0spo = CN0nom + deltaCN0;

    spoPeak = expectedPeaksize(CN0spo);
    
    % compute spoofed correlation function
    spoCorrFun = spoPeak .* Rcorr(x' - offsets);
    
    % sum with nominal values
    sumCorrFun = nomCorrFun  + spoCorrFun;

    %% compute nominal peak, spoofed peak
    
    peakIs = zeros(size(offsets));
    
    deltaMetric = zeros(N, length(offsets));
    measCN0 = zeros(N, length(offsets));
    
    for ii = 1:length(offsets)

        % find peak, assume perfect tracking
        [peakSize(deltaI, ii), peakIs(ii)] = max(sumCorrFun(:, ii));

        % run a Monte Carlo for Delta metric measurement
        corrTapStd = deltaSigmaVal(expectedCN0(peakSize(deltaI, ii)))/sqrt(2);
        corrTapNoise = corrTapStd*randn(N, length(xTI)); % is normalized by peaksize
        corrTapVals = sumCorrFun(peakIs(ii)+xTI, ii)' + peakSize(deltaI, ii)* corrTapNoise;
        
        deltaMetric(:, ii) = (corrTapVals(:, 1) - corrTapVals(:, 3)) ./ corrTapVals(:, 2);
        
        % get measured CN0 (mostly unaffected by noise on correlator taps)
        measCN0(:, ii) = expectedCN0(corrTapVals(:, 2));


    end
    
    % calculate log Lambda of the delta metric
    sigDelta = deltaSigmaVal(measCN0);
    logLaD = (deltaMetric ./ sigDelta).^2;
    
    % calculate log Lambda of the power metric, now do Monte Carlo for
    % noise on power metric (excluded before for comp. efficiency, does not
    % affect delta metric)
    % This includes extra noise on power measurement.
    logLaP = ((measCN0 + sigP*randn(N, length(offsets)) - CN0nom) / sigP).^2;
    
    % calculate alarms
    alarm.D = sum(logLaD > chi2inv(1-P_FA, 1), 1);
    alarm.P = sum(logLaP > chi2inv(1-P_FA, 1), 1);
    alarm.Dor = sum(logLaD > chi2inv(1-P_FA/2, 1), 1);
    alarm.Por = sum(logLaP > chi2inv(1-P_FA/2, 1), 1);
    alarm.OR = sum(logLaD > chi2inv(1-P_FA/2, 1) | logLaP > chi2inv(1-P_FA/2, 1), 1);
    alarm.Dand = sum(logLaD > chi2inv(1-sqrt(P_FA), 1), 1);
    alarm.Pand = sum(logLaP > chi2inv(1-sqrt(P_FA), 1), 1);
    alarm.AND = sum(logLaD > chi2inv(1-sqrt(P_FA), 1) & logLaP > chi2inv(1-sqrt(P_FA), 1), 1);
    alarm.joint = sum(logLaP + logLaD > chi2inv(1-P_FA, 2), 1);
    alarm.setOr = sum(layer*(logLaP + logLaD) > chi2inv(1-PFA_or/2, 1) | layer*(logLaP + logLaD) > chi2inv(1-PFA_or/2, 1), 1);
    alarm.setAnd = sum(layer*(logLaP .* logLaD) > chi2inv(1-sqrt(PFA_and), 1) & layer*(logLaP .* logLaD) > chi2inv(1-sqrt(PFA_and), 1), 1);
    alarm.multilayer = sum([alarm.setOr alarm.setAnd], 1);

    for metric = fields(alarm)'
        % minimum P_MD at best epoch
        P_MDmin.(metric{1})(deltaI) = 1 - max(alarm.(metric{1})) / N;
        % average P_MD across all epochs
        P_MDmean.(metric{1})(deltaI) = 1 - mean(alarm.(metric{1})) / N;
    end

end

%  Begin Set Switch Optimization
    
      if deltaCN0 <= 8
            mainSet  = P_MDmean.setOr;
            minorSet = P_MDmean.setAnd;
      elseif (8 < deltaCN0) && (deltaCN0 <= 12)
            minorSet = P_MDmean.setOr;
            mainSet = P_MDmean.setAnd;
      elseif deltaCN0 <= 20
            mainSet  = P_MDmean.setOr;
            minorSet = P_MDmean.setAnd;
      end  
%  End Set Switch Optimization

%% plot results
plt = PlotLatexStyle;

% Wheather or not to draw the new graph on the same space with the previous.
switch app.HoldGraphSwitch.Value
     case 'ON'
% cla(app.ResultsMLASC, 'reset')
     case 'OFF'
cla(app.ResultsMLASC, 'reset')
end

hold(app.ResultsMLASC,'on')
grid(app.ResultsMLASC, "off");

% \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
% Begin Switch between 3D and 2D Outputs 
% \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\

switch app.DimentionsSwitch.Value 
    % /////////////////
    % 1 Begin case 3D
    % /////////////////

    case '3D'
     xx = abs(deltaCN0s);
     
        yp =  N*(1-P_MDmean.P);
        [xxp,yyp] = meshgrid(xx,yp);
        zzp = ((yyp.*P_MDmean.P)/N); 
        
        yd =  N*(1-P_MDmean.D);
        [xxd,yyd] = meshgrid(xx,yd);
        zzd = ((yyd.*P_MDmean.D)/N);
        
        yo =  N*(1-P_MDmean.OR);
        [xxo,yyo] = meshgrid(xx,yo);
        zzo = ((yyo.*P_MDmean.OR)/N);
        
        ya =  N*(1-P_MDmean.AND);
        [xxa,yya] = meshgrid(xx,ya);
        zza = ((yya.*P_MDmean.AND)/N);
        
        yj =  N*(1-P_MDmean.joint);
        [xxj,yyj] = meshgrid(xx,yj);
        zzj= ((yyj.*P_MDmean.joint)/N);
        
        ymain =  N*(1-mainSet);
        [xxmain,yymain] = meshgrid(xx,ymain);
        zzmain= ((yymain.*mainSet)/N);
        
        yminor =  N*(1-minorSet);
        [xxminor,yyminor] = meshgrid(xx,yminor);
        zzminor= ((yyminor.*minorSet)/N);
        
        yml =  N*(1-P_MDmean.multilayer);
        [xxml,yyml] = meshgrid(xx,yml);
        zzml= ((yyml.*P_MDmean.multilayer)/N);
                

      % 1.1  - Begin Switch between Metrics Combinations, 3D 
        switch app.ListBox.Value
            case '所有指标组合'
                surf(app.ResultsMLASC,xxp,yyp,zzp)
                surf(app.ResultsMLASC,xxd,yyd,zzd)
                surf(app.ResultsMLASC,xxo,yyo,zzo)
                surf(app.ResultsMLASC,xxa,yya,zza)
                surf(app.ResultsMLASC,xxj,yyj,zzj)
                surf(app.ResultsMLASC,xxmain,yymain,zzmain)
                surf(app.ResultsMLASC,xxminor,yyminor,zzminor)
                surf(app.ResultsMLASC,xxml,yyml,zzml)
               % legend(app.ResultsMLASC, 'P', 'D', 'OR', 'AND', 'JOINT', 'MAIN SET', 'MINOR SET',  'MLASC','FontSize',9,'FontWeight', 'Bold')
            case 'MLASC vs 主要 - 次要 vs 联合'
                surf(app.ResultsMLASC,xxj,yyj,zzj)
                surf(app.ResultsMLASC,xxmain,yymain,zzmain)
                surf(app.ResultsMLASC,xxminor,yyminor,zzminor)
                surf(app.ResultsMLASC,xxml,yyml,zzml)
              %  legend(app.ResultsMLASC, 'JOINT', 'MAIN SET', 'MINOR SET', 'MLASC','FontSize',9,'FontWeight', 'Bold')
            case 'MLASC vs联合组合'
                surf(app.ResultsMLASC,xxj,yyj,zzj)
                surf(app.ResultsMLASC,xxml,yyml,zzml)
               % legend(app.ResultsMLASC, 'JOINT', 'MLASC','FontSize',9,'FontWeight', 'Bold')
            case '主 – 次要 vs 与– 或逻辑门'
                surf(app.ResultsMLASC,xxo,yyo,zzo)
                surf(app.ResultsMLASC,xxa,yya,zza)
                surf(app.ResultsMLASC,xxmain,yymain,zzmain)
                surf(app.ResultsMLASC,xxminor,yyminor,zzminor)
              %  legend(app.ResultsMLASC, 'OR', 'AND', 'MAIN SET', 'MINOR SET','FontSize',9,'FontWeight', 'Bold')
            case '主要和次要集合'
                surf(app.ResultsMLASC,xxmain,yymain,zzmain)
                surf(app.ResultsMLASC,xxminor,yyminor,zzminor)
           %     legend(app.ResultsMLASC, 'MAIN SET', 'MINOR SET','FontSize',9,'FontWeight', 'Bold')
            case '与或门组合'
                surf(app.ResultsMLASC,xxo,yyo,zzo)
                surf(app.ResultsMLASC,xxa,yya,zza)
           %     legend(app.ResultsMLASC, 'OR', 'AND','FontSize',9,'FontWeight', 'Bold')
            case 'MLASC 组合'       
                surf(app.ResultsMLASC,xxml,yyml,zzml)               
                legend(app.ResultsMLASC, 'MLASC','FontSize',9,'FontWeight', 'Bold')
            case '功率指标'
                surf(app.ResultsMLASC,xxp,yyp,zzp)
                plt.latexLegend(app.ResultsMLASC, 'P')
            case '失真指标'
                surf(app.ResultsMLASC,xxd,yyd,zzd)
                 plt.latexLegend(app.ResultsMLASC, 'D')
            case '或逻辑门组合'
                surf(app.ResultsMLASC,xxo,yyo,zzo)
                 plt.latexLegend(app.ResultsMLASC, 'OR')
            case '与逻辑门组合'
               surf(app.ResultsMLASC,xxa,yya,zza)
                plt.latexLegend(app.ResultsMLASC, 'AND')
            case '联合组合'
              surf(app.ResultsMLASC,xxj,yyj,zzj)
               plt.latexLegend(app.ResultsMLASC, 'JOINT')

        end
        
       % /////////////////
       % 1.1 - End of Switch between Metrics Combinations, 3D 
       % /////////////////

        view(app.ResultsMLASC,[160,10])
        colormap (app.ResultsMLASC,"jet(500)")
        shading(app.ResultsMLASC, "interp")
        cb = colorbar(app.ResultsMLASC);
        cb.Title
        cb.Title.String = "Detection Probability";
        
        xlabel(app.ResultsMLASC,'SPA (dB)', plt.axisLabelArgs{:})
        ylabel(app.ResultsMLASC,'Alarms', plt.axisLabelArgs{:})
        zlabel(app.ResultsMLASC,'Missed Detection Probability', plt.axisLabelArgs{:})

    % /////////////////
    % 1 End case 3D
    % /////////////////

    % /////////////////
    % 2 Begin case 2D
    % /////////////////
    
    case '2D'

      
     % 2.1 - Begin of Switch between PMD and ALARMS, 2D 
            switch app.PmdAlarmsSwitch.Value
            
            % /////////////////
            % 2.1.1 Begin case PMD
            % /////////////////
            case 'PMD' 
            %% plot results % plot average P_MD in %
            
                % /////////////////
                % 2.1.1.1 Begin Switch between Metrics Combinations, PMD 
                % /////////////////
                
                switch app.ListBox.Value
                    case '所有指标组合'
                    plot(app.ResultsMLASC,deltaCN0s, P_MDmean.P*100, '-+', 'LineWidth', 1.2)    
                    plot(app.ResultsMLASC,deltaCN0s, P_MDmean.D*100, 'LineWidth', 1.2)
                    plot(app.ResultsMLASC,deltaCN0s, P_MDmean.OR*100, '-.', 'LineWidth', 1.2)
                    plot(app.ResultsMLASC,deltaCN0s, P_MDmean.AND*100, '--', 'LineWidth', 1.2)
                    plot(app.ResultsMLASC,deltaCN0s, P_MDmean.joint*100, '-d', 'LineWidth', 1.2)
                    plot(app.ResultsMLASC,deltaCN0s, mainSet*100, '--o', 'LineWidth', 2)
                    plot(app.ResultsMLASC,deltaCN0s, minorSet*100, '--*', 'LineWidth', 2)
                    plot(app.ResultsMLASC,deltaCN0s, P_MDmean.multilayer*100, '- pentagram', 'LineWidth', 2)
                    legend(app.ResultsMLASC, 'P', 'D', 'OR', 'AND', 'JOINT', 'MAIN SET', 'MINOR SET', 'MLASC','FontSize',10,'FontWeight', 'Bold')
                    case 'MLASC vs 主要 - 次要 vs 联合'
                        plot(app.ResultsMLASC,deltaCN0s, P_MDmean.joint*100, '-d', 'LineWidth', 1.2)
                        plot(app.ResultsMLASC,deltaCN0s, mainSet*100, '--o', 'LineWidth', 2)
                        plot(app.ResultsMLASC,deltaCN0s, minorSet*100, '--*', 'LineWidth', 2)
                        plot(app.ResultsMLASC,deltaCN0s, P_MDmean.multilayer*100, '- pentagram', 'LineWidth', 2)
                        plt.latexLegend(app.ResultsMLASC, 'JOINT', 'MAIN SET', 'MINOR SET', 'MLASC')
                    case 'MLASC vs联合组合'
                        plot(app.ResultsMLASC,deltaCN0s, P_MDmean.joint*100, '-d', 'LineWidth', 1.2)
                        plot(app.ResultsMLASC,deltaCN0s, P_MDmean.multilayer*100, '- pentagram', 'LineWidth', 2)
                        plt.latexLegend(app.ResultsMLASC, 'JOINT', 'MLASC')
                    case '主 – 次要 vs 与– 或逻辑门'
                        plot(app.ResultsMLASC,deltaCN0s, P_MDmean.OR*100, '-.', 'LineWidth', 1.2)
                        plot(app.ResultsMLASC,deltaCN0s, P_MDmean.AND*100, '--', 'LineWidth', 1.2)
                        plot(app.ResultsMLASC,deltaCN0s, mainSet*100, '--o', 'LineWidth', 2)
                        plot(app.ResultsMLASC,deltaCN0s, minorSet*100, '--*', 'LineWidth', 2)
                        plt.latexLegend(app.ResultsMLASC, 'OR', 'AND', 'MAIN SET', 'MINOR SET','FontSize',9,'FontWeight', 'Bold')
                    case '主要和次要集合'
                        plot(app.ResultsMLASC,deltaCN0s, mainSet*100, '--o', 'LineWidth', 2)
                        plot(app.ResultsMLASC,deltaCN0s, minorSet*100, '--*', 'LineWidth', 2)
                        plt.latexLegend(app.ResultsMLASC, 'MAIN SET', 'MINOR SET')
                    case '与或门组合'
                        plot(app.ResultsMLASC,deltaCN0s, P_MDmean.AND*100, '--', 'LineWidth', 1.2)
                        plot(app.ResultsMLASC,deltaCN0s, P_MDmean.OR*100, '-.', 'LineWidth', 1.2)
                        plt.latexLegend(app.ResultsMLASC, 'AND', 'OR')
                    case '功率指标'
                        plot(app.ResultsMLASC,deltaCN0s, P_MDmean.P*100, '-+', 'LineWidth', 1.2)
                        plt.latexLegend(app.ResultsMLASC, 'P')
                    case '失真指标'
                        plot(app.ResultsMLASC,deltaCN0s, P_MDmean.D*100, 'LineWidth', 1.2)
                        plt.latexLegend(app.ResultsMLASC, 'D')
                    case '或逻辑门组合'
                        plot(app.ResultsMLASC,deltaCN0s, P_MDmean.OR*100, '-.', 'LineWidth', 1.2)
                        plt.latexLegend(app.ResultsMLASC, 'OR')
                    case '与逻辑门组合'
                        plot(app.ResultsMLASC,deltaCN0s, P_MDmean.AND*100, '--', 'LineWidth', 1.2)
                        plt.latexLegend(app.ResultsMLASC, 'AND')
                    case '联合组合'
                        plot(app.ResultsMLASC,deltaCN0s, P_MDmean.joint*100, '-d', 'LineWidth', 1.2)
                        plt.latexLegend(app.ResultsMLASC, 'JOINT')
                   case 'MLASC 组合'       
                      plot(app.ResultsMLASC,deltaCN0s, P_MDmean.multilayer*100, '- pentagram', 'LineWidth', 2)
                      plt.latexLegend(app.ResultsMLASC, 'MLASC')
                end
                % /////////////////
                % 2.1.1.1 End Switch between Metrics Combinations, PMD 
                % /////////////////

        
            xlabel(app.ResultsMLASC,'Spoofer power advantage in (dB)', plt.axisLabelArgs{:})
            ylabel(app.ResultsMLASC,'Average $P_{MD}$ during lift-off in $\%$ ', plt.axisLabelArgs{:})
            
            % /////////////////
            % 2.1.1 End case PMD
            % /////////////////


            % /////////////////
            % 2.1.2 Begin case ALARMS
            % /////////////////
            
            case '警报'
         
                % /////////////////
                % 2.1.2.1 Begin Switch between Metrics Combinations, ALARMS 
                % /////////////////
                
                switch app.ListBox.Value
                         case '所有指标组合'
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-P_MDmean.P), '-+', 'LineWidth', 1.2)
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-P_MDmean.D), 'LineWidth', 1.2)
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-P_MDmean.OR), '-.', 'LineWidth', 1.2)  
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-P_MDmean.AND), '--', 'LineWidth', 1.2)
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-P_MDmean.joint), '-d', 'LineWidth', 1.2)
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-mainSet),'--o', 'LineWidth', 2)
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-minorSet), '--*', 'LineWidth', 2)
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-P_MDmean.multilayer), '- pentagram', 'LineWidth', 2)
                    legend(app.ResultsMLASC, 'P', 'D', 'OR', 'AND', 'JOINT', 'MAIN SET', 'MINOR SET', 'MLASC','FontSize',10,'FontWeight', 'Bold')
                         case 'MLASC vs 主要 - 次要 vs 联合'
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-P_MDmean.joint), '-d', 'LineWidth', 1.2)
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-mainSet),'--o', 'LineWidth', 2)
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-minorSet), '--*', 'LineWidth', 2)
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-P_MDmean.multilayer), '- pentagram', 'LineWidth', 2)
                    plt.latexLegend(app.ResultsMLASC, 'JOINT', 'MAIN SET', 'MINOR SET', 'MLASC')
                         case 'MLASC vs联合组合'
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-P_MDmean.joint), '-d', 'LineWidth', 1.2)
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-P_MDmean.multilayer), '- pentagram', 'LineWidth', 2)
                        plt.latexLegend(app.ResultsMLASC, 'JOINT','MLASC')
                        case '主 – 次要 vs 与– 或逻辑门'
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-P_MDmean.OR), '-.', 'LineWidth', 1.2)
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-P_MDmean.AND), '--', 'LineWidth', 1.2)
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-mainSet),'--o', 'LineWidth', 2)
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-minorSet), '--*', 'LineWidth', 2)
                    plt.latexLegend(app.ResultsMLASC, 'OR', 'AND', 'MAIN SET', 'MINOR SET','FontSize',9,'FontWeight', 'Bold')
                         case '主要和次要集合'
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-mainSet),'--o', 'LineWidth', 2)
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-minorSet), '--*', 'LineWidth', 2)
                        plt.latexLegend(app.ResultsMLASC, 'MAIN SET', 'MINOR SET')
                         case '与或门组合'
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-P_MDmean.AND), '--', 'LineWidth', 1.2)
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-P_MDmean.OR), '-.', 'LineWidth', 1.2)
                        plt.latexLegend(app.ResultsMLASC, 'AND', 'OR')
                        case '功率指标'
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-P_MDmean.P), '-+', 'LineWidth', 1.2)
                    plt.latexLegend(app.ResultsMLASC, 'P')
                        case '失真指标'
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-P_MDmean.D), 'LineWidth', 1.2)
                    plt.latexLegend(app.ResultsMLASC, 'D')
                        case '或逻辑门组合'
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-P_MDmean.OR), '-.', 'LineWidth', 1.2)
                    plt.latexLegend(app.ResultsMLASC, 'OR')
                        case '与逻辑门组合'
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-P_MDmean.AND), '--', 'LineWidth', 1.2)
                    plt.latexLegend(app.ResultsMLASC, 'AND')
                        case '联合组合'
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-P_MDmean.joint), '-d', 'LineWidth', 1.2)
                    plt.latexLegend(app.ResultsMLASC, 'JOINT')
                        case 'MLASC 组合'       
                    plot(app.ResultsMLASC,deltaCN0s, N*(1-P_MDmean.multilayer), '- pentagram', 'LineWidth', 2)
                    plt.latexLegend(app.ResultsMLASC, 'MLASC')

                end
                % /////////////////
                % 2.1.2.1 End Switch between Metrics Combinations, ALARMS 
                % /////////////////
            
            xlabel(app.ResultsMLASC,'Spoofer power advantage in (dB)', plt.axisLabelArgs{:})
            ylabel(app.ResultsMLASC,'Number of alarms', plt.axisLabelArgs{:})
            % /////////////////
            % 2.1.2 End case ALARMS
            % /////////////////
            end           
   % 2.1 - End of Switch between PMD and ALARMS, 2D 

end
% \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
% End Switch between 3D and 2D Outputs 
% \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\


%% helper functions
function ps = expectedPeaksize(CN0, N0)
%Calculate the theoretical peak size based on CN0, N0.

if nargin < 2
    N0 = app.BackgroundNoise.Value;
end

ps = sqrt(10.^((CN0 + N0)/10))*10^12 .* 500;

end

function eCN0 = expectedCN0(peaksize, N0)
%Compute the expected CN0 as a function of correlation peak size.

if nargin < 2
    N0 = app.BackgroundNoise.Value;
end

% compute the expected C/N0
eCN0 = 10 * log10((2*peaksize /1e15).^2) - N0;

end 
        end

        % Value changed function: NominalSignalPower
        function NominalSignalPowerValueChanged(app, event)
            app.NominalSignalPower.Value;
        end

        % Value changed function: EarlyLateCorrelator
        function EarlyLateCorrelatorValueChanged(app, event)
            app.EarlyLateCorrelator.Value;
        end

        % Value changed function: FalseAlert
        function FalseAlertValueChanged(app, event)
            app.FalseAlert.Value;
        end

        % Value changed function: LowestSPA
        function LowestSPAValueChanged(app, event)
            app.LowestSPA.Value;
        end

        % Value changed function: HighestSPA
        function HighestSPAValueChanged(app, event)
            app.HighestSPA.Value;
        end

        % Value changed function: MonteCarloRuns
        function MonteCarloRunsValueChanged(app, event)
            app.MonteCarloRuns.Value;
        end

        % Value changed function: LayersDisplay
        function LayersDisplayValueChanged(app, event)
            app.LayersDisplay.Value;
        end

        % Value changed function: BackgroundNoise
        function BackgroundNoiseValueChanged(app, event)
            app.BackgroundNoise.Value;
        end

        % Value changed function: PFAor
        function PFAorValueChanged(app, event)
            app.PFAor.Value;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            cla(app.ResultsMLASC, 'reset')
            app.PercentValue.Value = 0;
            app.CurrentLayer.Value = 0;    
            app.RunSIm.Text = '运行仿真并绘制结果';
            app.RunSIm.Icon = '';
        end

        % Value changed function: CurrentLayer
        function CurrentLayerValueChanged(app, event)
            app.CurrentLayer.Value;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
% prepare workspace


close all;

rng(1); % for reproducability

%% set simulation paramters

CN0nom = app.NominalSignalPower.Value; % nominal signal strength in dB-Hz
sigP = app.SDpowerMetric.Value; % standard deviation of power metric in dB
ELdist = app.EarlyLateCorrelator.Value; % early/late correlator positions in chips from prompt
P_FA = app.FalseAlert.Value; % false alert probability
lspa = app.LowestSPA.Value;
hspa = app.HighestSPA.Value;
deltaCN0s = lspa:hspa; % different spoofer power advantages in dB
N = app.MonteCarloRuns.Value; % number of monte carlo runs at every epoch 
PFA_or = app.PFAor.Value;
PFA_and = app.PFAand.Value;
layer = app.LayersDisplay.Value;
app.CurrentLayer.Value = layer;
offsets = 0:0.01:(1+ELdist+0.01); % offsets of spoofer's triangle in code chips
dx = 0.001;
x = -1-min(offsets):dx:1+max(offsets); % code chip range around authentic peak
xTI = round(ELdist/dx) * [-1 0 1]; % correlation tab spacing in units of x

app.RunSIm.Text = '数据生成正在进行中';
app.RunSIm.Icon = 'loading.gif';
for loader = 0:1
loader = loader + 1/N;
end
h=waitbar(loader,'数据生成正在进行中');
app.RunSIm.Text = '保存为 MLASST_DATASET.mat 的数据';
app.RunSIm.Icon = 'tick.png';
app.PercentValue.Value = 100;
close(h);

% calculate height of nominal signal peak at each epoch
nomPeak = expectedPeaksize(CN0nom);
nomCorrFun = nomPeak .* Rcorr(x'); % nominal correlation triangle

%% run simulation during lift-off
deltaMetric = zeros(length(deltaCN0s), length(offsets));
peakSize = zeros(length(deltaCN0s), length(offsets));

[meanLLD, maxLLD, meanLLP, maxLLP] = deal(zeros(1, length(deltaCN0s)));

for deltaI = 1:length(deltaCN0s)
    deltaCN0 = deltaCN0s(deltaI); % spoofer power advantage


    %% set peak sizes
    CN0spo = CN0nom + deltaCN0;

    spoPeak = expectedPeaksize(CN0spo);
    
    % compute spoofed correlation function
    spoCorrFun = spoPeak .* Rcorr(x' - offsets);
    
    % sum with nominal values
    sumCorrFun = nomCorrFun  + spoCorrFun;

    %% compute nominal peak, spoofed peak
    
    peakIs = zeros(size(offsets));
    
    deltaMetric = zeros(N, length(offsets));
    measCN0 = zeros(N, length(offsets));
    
    for ii = 1:length(offsets)

        % find peak, assume perfect tracking
        [peakSize(deltaI, ii), peakIs(ii)] = max(sumCorrFun(:, ii));

        % run a Monte Carlo for Delta metric measurement
        corrTapStd = deltaSigmaVal(expectedCN0(peakSize(deltaI, ii)))/sqrt(2);
        corrTapNoise = corrTapStd*randn(N, length(xTI)); % is normalized by peaksize
        corrTapVals = sumCorrFun(peakIs(ii)+xTI, ii)' + peakSize(deltaI, ii)* corrTapNoise;
        
        deltaMetric(:, ii) = (corrTapVals(:, 1) - corrTapVals(:, 3)) ./ corrTapVals(:, 2);
        
        % get measured CN0 (mostly unaffected by noise on correlator taps)
        measCN0(:, ii) = expectedCN0(corrTapVals(:, 2));


    end
    
    % calculate log Lambda of the delta metric
    sigDelta = deltaSigmaVal(measCN0);
    logLaD = (deltaMetric ./ sigDelta).^2;
    
    % calculate log Lambda of the power metric, now do Monte Carlo for
    % noise on power metric (excluded before for comp. efficiency, does not
    % affect delta metric)
    % This includes extra noise on power measurement.
    logLaP = ((measCN0 + sigP*randn(N, length(offsets)) - CN0nom) / sigP).^2;
    
    % calculate alarms
    alarm.D = sum(logLaD > chi2inv(1-P_FA, 1), 1);
    alarm.P = sum(logLaP > chi2inv(1-P_FA, 1), 1);
    alarm.Dor = sum(logLaD > chi2inv(1-P_FA/2, 1), 1);
    alarm.Por = sum(logLaP > chi2inv(1-P_FA/2, 1), 1);
    alarm.OR = sum(logLaD > chi2inv(1-P_FA/2, 1) | logLaP > chi2inv(1-P_FA/2, 1), 1);
    alarm.Dand = sum(logLaD > chi2inv(1-sqrt(P_FA), 1), 1);
    alarm.Pand = sum(logLaP > chi2inv(1-sqrt(P_FA), 1), 1);
    alarm.AND = sum(logLaD > chi2inv(1-sqrt(P_FA), 1) & logLaP > chi2inv(1-sqrt(P_FA), 1), 1);
    alarm.joint = sum(logLaP + logLaD > chi2inv(1-P_FA, 2), 1);
    alarm.setOr = sum(layer*(logLaP + logLaD) > chi2inv(1-PFA_or/2, 1) | layer*(logLaP + logLaD) > chi2inv(1-PFA_or/2, 1), 1);
    alarm.setAnd = sum(layer*(logLaP .* logLaD) > chi2inv(1-sqrt(PFA_and), 1) & layer*(logLaP .* logLaD) > chi2inv(1-sqrt(PFA_and), 1), 1);
    alarm.multilayer = sum([alarm.setOr alarm.setAnd], 1);

    for metric = fields(alarm)'
        % minimum P_MD at best epoch
        P_MDmin.(metric{1})(deltaI) = 1 - max(alarm.(metric{1})) / N;
        % average P_MD across all epochs
        P_MDmean.(metric{1})(deltaI) = 1 - mean(alarm.(metric{1})) / N;
    end

end

save MLASST_DATASET.mat alarm spoCorrFun sumCorrFun logLaD logLaP measCN0 sigDelta corrTapNoise corrTapVals deltaMetric nomCorrFun maxLLD maxLLP meanLLD meanLLP CN0nom CN0spo  corrTapStd  offsets deltaCN0 deltaCN0s deltaI dx ELdist ii N P_FA nomPeak P_MDmean P_MDmin peakIs peakSize sigP spoPeak x xTI
  %% helper functions
function ps = expectedPeaksize(CN0, N0)
%Calculate the theoretical peak size based on CN0, N0.

if nargin < 2
    N0 = app.BackgroundNoise.Value;
end

ps = sqrt(10.^((CN0 + N0)/10))*10^12 .* 500;

end

function eCN0 = expectedCN0(peaksize, N0)
%Compute the expected CN0 as a function of correlation peak size.

if nargin < 2
    N0 = app.BackgroundNoise.Value;
end

% compute the expected C/N0
eCN0 = 10 * log10((2*peaksize /1e15).^2) - N0;

end     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MLASSTUIFigure = uifigure('Visible', 'off');
            app.MLASSTUIFigure.Name = 'PD DEFENSE SOFTWARE - NUAA';
            app.TabGroup = uitabgroup(app.MLASSTUIFigure);
            app.TabGroup.Tooltip = {''};
            app.MLASSTTab = uitab(app.TabGroup);
            app.MLASSTTab.Title = 'MLASST';
            app.ResultsMLASC = uiaxes(app.MLASSTTab);
            app.ResultsMLASC.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ResultsMLASC.XTickLabel = '';
            app.ResultsMLASC.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ResultsMLASC.YTickLabel = '';
            app.Panel_12 = uipanel(app.MLASSTTab);
            app.Label = uilabel(app.Panel_12);
            app.Label.Text = '允许多个绘';
            app.HoldGraphSwitch = uiswitch(app.Panel_12, 'slider');
            app.HoldGraphSwitch.Items = {'OFF', 'ON'};
            app.HoldGraphSwitch.Value = 'OFF';
            app.Panel_11 = uipanel(app.MLASSTTab);
            app.LayersDisplayLabel = uilabel(app.Panel_11);
            app.LayersDisplayLabel.Text = '层数 (1,2,3...)';
            app.Panel_10 = uipanel(app.MLASSTTab);
            app.MonteCarloRunsLabel = uilabel(app.Panel_10);
            app.MonteCarloRunsLabel.Text = {'每个时期的蒙特卡洛运行次数'; ''; ''};
            app.Panel_7 = uipanel(app.MLASSTTab);
            app.Panel_9 = uipanel(app.Panel_7);
            app.FalseAlert = uieditfield(app.Panel_9, 'numeric');
            app.FalseAlert.ValueChangedFcn = createCallbackFcn(app, @FalseAlertValueChanged, true);
            app.FalseAlert.Value = 1e-08;
            app.FalseAlertLabel = uilabel(app.Panel_9);
            app.FalseAlertLabel.Text = '报错概率';
            app.HighestSPA = uieditfield(app.Panel_9, 'numeric');
            app.HighestSPA.ValueChangedFcn = createCallbackFcn(app, @HighestSPAValueChanged, true);
            app.HighestSPA.Value = 20;
            app.HighestSPALabel = uilabel(app.Panel_9);
            app.HighestSPALabel.Text = '最高欺骗器功率优势 （dB）';
            app.EarlyLateCorrelator = uieditfield(app.Panel_9, 'numeric');
            app.EarlyLateCorrelator.ValueChangedFcn = createCallbackFcn(app, @EarlyLateCorrelatorValueChanged, true);
            app.EarlyLateCorrelator.Value = 0.052;
            app.EarlyLateCorrelatorLabel = uilabel(app.Panel_9);
            app.EarlyLateCorrelatorLabel.Text = '早期/晚期相关器位置';
            app.LowestSPALabel = uilabel(app.Panel_9);
            app.LowestSPALabel.Text = '最低欺骗器功率优势 （dB）';
            app.LowestSPA = uieditfield(app.Panel_9, 'numeric');
            app.LowestSPA.ValueDisplayFormat = '%.0f';
            app.LowestSPA.ValueChangedFcn = createCallbackFcn(app, @LowestSPAValueChanged, true);
            app.LowestSPA.Value = 3;
            app.SDpowerMetric = uieditfield(app.Panel_9, 'numeric');
            app.SDpowerMetric.ValueDisplayFormat = '%.0f';
            app.SDpowerMetric.Value = 2;
            app.SDpowerMetricLabel = uilabel(app.Panel_9);
            app.SDpowerMetricLabel.Text = '功率指标的标准偏差 （dB）';
            app.NominalSignalPower = uieditfield(app.Panel_9, 'numeric');
            app.NominalSignalPower.ValueDisplayFormat = '%.0f';
            app.NominalSignalPower.ValueChangedFcn = createCallbackFcn(app, @NominalSignalPowerValueChanged, true);
            app.NominalSignalPower.Value = 40;
            app.NominalSignalPowerLabel = uilabel(app.Panel_9);
            app.NominalSignalPowerLabel.Text = '标称信号功率 （dB-Hz）';
            app.BackgroundNoiseLabel = uilabel(app.Panel_9);
            app.BackgroundNoiseLabel.Text = '背景噪声 （dB-Hz）';
            app.BackgroundNoise = uieditfield(app.Panel_9, 'numeric');
            app.BackgroundNoise.ValueDisplayFormat = '%.0f';
            app.BackgroundNoise.ValueChangedFcn = createCallbackFcn(app, @BackgroundNoiseValueChanged, true);
            app.BackgroundNoise.Value = -203;
            app.PFAor = uieditfield(app.Panel_9, 'numeric');
            app.PFAor.ValueChangedFcn = createCallbackFcn(app, @PFAorValueChanged, true);
            app.PFAor.Value = 6e-09;
            app.PFAorLabel = uilabel(app.Panel_9);
            app.PFAorLabel.Text = '主要集合的 PFA 份额';
            app.PFAand = uieditfield(app.Panel_9, 'numeric');
            app.PFAand.Value = 4e-09;
            app.PFAandLabel = uilabel(app.Panel_9);
            app.PFAandLabel.Text = '次要集合的 PFA 份额';
            app.ListBox = uilistbox(app.Panel_7);
            app.ListBox.Items = {'所有指标组合', 'MLASC vs 主要 - 次要 vs 联合', 'MLASC vs联合组合', '主 – 次要 vs 与– 或逻辑门', '主要和次要集合', '与或门组合', 'MLASC 组合', '联合组合', '与逻辑门组合', '或逻辑门组合', '功率指标', '失真指标'};
            app.ListBox.Value = '所有指标组合';
            app.Label_4 = uilabel(app.Panel_7);
            app.Label_4.Text = '选择用于模拟的指标';
            app.Panel_8 = uipanel(app.MLASSTTab);
            app.RunSIm = uibutton(app.Panel_8, 'push');
            app.RunSIm.ButtonPushedFcn = createCallbackFcn(app, @RunSImPushed, true);
            app.RunSIm.Text = '运行仿真并绘制结果';
            app.DimentionsSwitch = uiswitch(app.Panel_8, 'slider');
            app.DimentionsSwitch.Items = {'3D', '2D'};
            app.DimentionsSwitch.Value = '3D';
            app.PmdAlarmsSwitch = uiswitch(app.Panel_8, 'slider');
            app.PmdAlarmsSwitch.Items = {'PMD', '警报'};
            app.PmdAlarmsSwitch.Value = 'PMD';
            app.Button_2 = uibutton(app.Panel_8, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'data.png';
            app.Button_2.Text = '生成数据';
            app.GNSSSIGNALISOLATIONVIAPOWERANDDISTORTIONDYNAMICMONTECARLOLabel = uilabel(app.MLASSTTab);
            app.GNSSSIGNALISOLATIONVIAPOWERANDDISTORTIONDYNAMICMONTECARLOLabel.Text = 'M L A S S T 软 件  -  多 层 反 欺 骗 仿 真 工 具';
            app.Button = uibutton(app.MLASSTTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Clear.png';
            app.Button.Text = '清除所有内容';
            app.MonteCarloRuns = uieditfield(app.MLASSTTab, 'numeric');
            app.MonteCarloRuns.ValueDisplayFormat = '%.0f';
            app.MonteCarloRuns.ValueChangedFcn = createCallbackFcn(app, @MonteCarloRunsValueChanged, true);
            app.MonteCarloRuns.Value = 1000000;
            app.LayersDisplay = uieditfield(app.MLASSTTab, 'numeric');
            app.LayersDisplay.ValueDisplayFormat = '%.0f';
            app.LayersDisplay.ValueChangedFcn = createCallbackFcn(app, @LayersDisplayValueChanged, true);
            app.LayersDisplay.Value = 2;
            app.CHINAFLAG = uiimage(app.MLASSTUIFigure);
            app.CHINAFLAG.ImageSource = 'Chinese.png';
            app.USFLAG = uiimage(app.MLASSTUIFigure);
            app.USFLAG.ImageClickedFcn = createCallbackFcn(app, @USFLAGImageClicked, true);
            app.USFLAG.ImageSource = 'English.jpg';
            app.Label_2 = uilabel(app.MLASSTUIFigure);
            app.Label_2.Text = '仿真';
            app.Switch = uiswitch(app.MLASSTUIFigure, 'slider');
            app.Switch.Items = {'ENGLISH', '中文'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '中文';
            app.PercentSign = uieditfield(app.MLASSTUIFigure, 'text');
            app.PercentSign.Editable = 'off';
            app.PercentSign.Tooltip = {''};
            app.PercentSign.Value = '%';
            app.PercentValue = uieditfield(app.MLASSTUIFigure, 'numeric');
            app.PercentValue.ValueDisplayFormat = '%.0f';
            app.PercentValue.ValueChangedFcn = createCallbackFcn(app, @PercentValueValueChanged, true);
            app.PercentValue.Editable = 'off';
            app.Image = uiimage(app.MLASSTUIFigure);
            app.Image.ImageSource = 'heading.png';
            app.CurrentLayer = uieditfield(app.MLASSTUIFigure, 'numeric');
            app.CurrentLayer.ValueDisplayFormat = '%.0f';
            app.CurrentLayer.ValueChangedFcn = createCallbackFcn(app, @CurrentLayerValueChanged, true);
            app.CurrentLayer.Editable = 'off';
            app.Label_3 = uilabel(app.MLASSTUIFigure);
            app.Label_3.Text = '层';
            % Show the figure after all components are created
            app.MLASSTUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MLASST_CHINESE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MLASSTUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MLASSTUIFigure)
        end
    end
end